classdef SynthApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AmplitdueKnob                 matlab.ui.control.Knob
        AmplitdueKnobLabel            matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        SamplingFrequencySlider       matlab.ui.control.Slider
        SamplingFrequencySliderLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function results = func(app)
            
        end

        function plotGraphs(app, frequency, file_name, vol)
            [sound_file, fs] = audioread(file_name);
            f0 = 5; %frequency of square wave
            duration = 4; %duration
            A = 1 + (vol/100); %amplitude
            n = length(sound_file); 
            rate = 20; %sampling rate
            t = 0:1/fs:n/fs-1/fs;
            squareWave = A * square(2 * pi * f0 * t);
            lfo_square = zeros(size(sound_file));
            k = round(linspace(1,n,duration*rate));
            sampled_values = sound_file(k);
            for i= 1:length(k)-1
                lfo_square(k(i):k(i+1)) = sampled_values(i)*squareWave(k(i));
            end

            freq = frequency; %can be changed to increase the pitch
            input = sin(2*pi*freq*t); %can be changed into other types of waves?, this doesn't really do anything it just shows what the input wave is
            x = zeros(size(sound_file));
            for i = 1:length(k)-1
                lfo_square2 = sampled_values(i) * squareWave(k(i):k(i+1)-1);
                lfo_freq = freq * f0 * lfo_square2;
                t_1 = t(k(i):k(i+1)-1);
                lfo_input = sin(2*pi*lfo_freq.*t_1);
                x(k(i):k(i+1)-1) = lfo_input;
            end

            plot(app.UIAxes,t, sound_file)
            plot(app.UIAxes2,t, lfo_square)
            plot(app.UIAxes3,t,x)
            title(app.UIAxes3,'LFO Applied to Input Wave');
            soundsc(x, fs); 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
        end

        % Callback function: SamplingFrequencySlider, UIAxes
        function Sound_SliderValueChanged(app, event)
            value = app.SamplingFrequencySlider.Value;
            if(app.FileNameEditField.Value)
                plotGraphs(app, value, app.FileNameEditField.Value, app.AmplitdueKnob.Value);
            end
            
        end

        % Callback function: FileNameEditField, UIAxes3
        function FileEditFieldValueChanged(app, event)
           value = app.FileNameEditField.Value;
           if(app.SamplingFrequencySlider.Value ~= 0)
               plotGraphs(app, app.SamplingFrequencySlider.Value, value, app.AmplitdueKnob.Value);
           else
               plotGraphs(app, 440, value, app.AmplitdueKnob.Value);

           end
        end

        % Value changed function: AmplitdueKnob
        function AmplitdueKnobValueChanged(app, event)
            value = app.AmplitdueKnob.Value;
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.SamplingFrequencySlider.Value, app.FileNameEditField.Value, value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'LFO Applied to Input Wave')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitdue')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Generated LFO')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitdue')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sound')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @Sound_SliderValueChanged, true);
            app.SamplingFrequencySliderLabel = uilabel(app.UIFigure);
            app.SamplingFrequencySliderLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencySlider = uislider(app.UIFigure);
            app.SamplingFrequencySlider.Limits = [200 700];
            app.SamplingFrequencySlider.MajorTicks = [200 300 400 500 600 700];
            app.SamplingFrequencySlider.MajorTickLabels = {'200', '300', '400', '500', '600', '700'};
            app.SamplingFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @Sound_SliderValueChanged, true);
            app.SamplingFrequencySlider.MinorTicks = [];
            app.SamplingFrequencySlider.Value = 200;
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.AmplitdueKnobLabel = uilabel(app.UIFigure);
            app.AmplitdueKnobLabel.Text = 'Amplitdue';
            app.AmplitdueKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitdueKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitdueKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynthApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end