classdef SynthApp_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectedWaveLabel             matlab.ui.control.Label
        SelectWaveLabel               matlab.ui.control.Label
        TriangularButton              matlab.ui.control.Button
        SawtoothButton                matlab.ui.control.Button
        SquarewaveButton              matlab.ui.control.Button
        SinewaveButton                matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        DropDown                      matlab.ui.control.DropDown
        SamplingRateSlider            matlab.ui.control.Slider
        SamplingRateSliderLabel       matlab.ui.control.Label
        LFOParametersLabel            matlab.ui.control.Label
        LowerFrequencyEditField       matlab.ui.control.NumericEditField
        LowerFrequencyEditFieldLabel  matlab.ui.control.Label
        UpperFrequencyEditField       matlab.ui.control.NumericEditField
        UpperFrequencyEditFieldLabel  matlab.ui.control.Label
        BandstopFilterLabel           matlab.ui.control.Label
        AddNoiseSlider                matlab.ui.control.Slider
        AddNoiseLabel                 matlab.ui.control.Label
        AmplitudeKnob                 matlab.ui.control.Knob
        AmplitudeKnobLabel            matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        FrequencySlider               matlab.ui.control.Slider
        FrequencySliderLabel          matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function results = func(app)
        end

        function plotGraphs(app, frequency, file_name, vol, sampling_rate, wave)            
            [sound_file, fs] = audioread(file_name);
            for n=1:length(sound_file)-1
                sound_file(n) = sound_file(n)*(1+vol);
            end

            f0 = 5; %frequency of square wave
            duration = 4; %duration
            A = 1; %amplitude
            n = length(sound_file); 
            rate = sampling_rate; %sampling rate
            t = 0:1/fs:n/fs-1/fs;
            squareWave = A * square(2 * pi * f0 * t);
            lfo_square = zeros(size(sound_file));
            k = round(linspace(1,n,duration*rate));
            sampled_values = sound_file(k);
            for i= 1:length(k)-1
                lfo_square(k(i):k(i+1)) = sampled_values(i)*squareWave(k(i));
            end

            freq = frequency; %can be changed to increase the pitch
            x = zeros(size(sound_file));
            for i = 1:length(k)-1
                lfo_square2 = sampled_values(i) * squareWave(k(i):k(i+1)-1);
                lfo_freq = freq * f0 * lfo_square2;
                t_1 = t(k(i):k(i+1)-1);
                
                switch (wave)
                    case "Sinewave"
                         lfo_input = sin(2*pi*lfo_freq.*t_1);
                    case "Squarewave"
                         lfo_input = sin(lfo_freq.*t_1) + sin(3*lfo_freq.*t_1)/3 + sin(5*lfo_freq.*t_1)/5 + sin(7*lfo_freq.*t_1)/7 + sin(9*lfo_freq.*t_1)/9;
                    case "Sawtooth"
                         lfo_input = sawtooth(2*pi*lfo_freq.*t_1);
                    case "Triangular"
                         lfo_input = sawtooth(2*pi*lfo_freq.*t_1, 1/2);
                    otherwise
                         lfo_input = sin(2*pi*lfo_freq.*t_1);
                end

                x(k(i):k(i+1)-1) = lfo_input;
            end

            plot(app.UIAxes,t, sound_file)
            plot(app.UIAxes2,t, lfo_square)
            plot(app.UIAxes3,t,x)
            title(app.UIAxes3,'LFO Applied to Input Wave');
            soundsc(x, fs); 
        end
        
        function bandstopFilter(app, upper_cutoff, lower_cutoff)
            f_lower = lower_cutoff;
            f_upper = upper_cutoff; 
            
            [sound_file, fs] = audioread(app.FileNameEditField.Value);
            n = length(sound_file); 
            t = 0:1/fs:n/fs-1/fs;
            % start:increment:end

            x1 = mean(sound_file, 2).';
            y = fft(x1);
            f1 = fs*(0:(n-1))/n;
            
            filter = zeros(1,n);
            filter(f1<f_lower) = 1;
            filter(f1>f_upper) = 1;
            
            x_filteredf = y .* filter;
            x_filtered = real(ifft(x_filteredf));

            plot(app.UIAxes4, t, x_filtered);
            plot(app.UIAxes5, f1, abs(x_filteredf));

            soundsc(x_filtered, fs); 
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
        end

        % Callback function: FrequencySlider, UIAxes
        function Sound_SliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            if(app.FileNameEditField.Value)
                plotGraphs(app, value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end

        % Callback function
        function FileEditFieldValueChanged(app, event)

        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end

        % Value changed function: AddNoiseSlider
        function AddNoiseSliderValueChanged(app, event)
            value = app.AddNoiseSlider.Value;
            if(app.FileNameEditField.Value)
                [sound_file, fs] = audioread(app.FileNameEditField.Value);
                percentOfNoise = value/100; % Ex: 0.1 = 10% noise being added, at 0.0 or 0% no 
                          %noise is being added, so the sound plays normally
                noiseAmplitude = percentOfNoise * sound_file;
                sound_file = sound_file + noiseAmplitude .* rand(size(sound_file));
                soundsc(sound_file, fs); 
            end
        end

        % Value changed function: UpperFrequencyEditField
        function UpperFrequencyEditFieldValueChanged(app, event)
            value = app.UpperFrequencyEditField.Value;
            if(app.FileNameEditField.Value)
                bandstopFilter(app, value, app.LowerFrequencyEditField.Value);
            end

        end

        % Value changed function: LowerFrequencyEditField
        function LowerFrequencyEditFieldValueChanged(app, event)
            value = app.LowerFrequencyEditField.Value;
            if(app.FileNameEditField.Value)
                bandstopFilter(app, app.UpperFrequencyEditField.Value, value);
            end

        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            plotGraphs(app, app.FrequencySlider.Value, value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
        end

        % Value changed function: SamplingRateSlider
        function SamplingRateSliderValueChanged(app, event)
            value = app.SamplingRateSlider.Value;
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, value, app.DropDown.Value);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %tempval1 = app.DropDown.Value;
            value = app.DropDown.Value;
            if (app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value. app.SamplingRateSlider.Value, value);
            end
        end

        % Button pushed function: SinewaveButton
        function SinewaveButtonPushed(app, event)
            app.DropDown.Value = app.DropDown.Items(1);
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end

        % Button pushed function: SquarewaveButton
        function SquarewaveButtonPushed(app, event)
            app.DropDown.Value = app.DropDown.Items(2);
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end

        % Button pushed function: SawtoothButton
        function SawtoothButtonPushed(app, event)
            app.DropDown.Value = app.DropDown.Items(3);
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end

        % Button pushed function: TriangularButton
        function TriangularButtonPushed(app, event)
            app.DropDown.Value = app.DropDown.Items(4);
            if(app.FileNameEditField.Value)
                plotGraphs(app, app.FrequencySlider.Value, app.FileNameEditField.Value, app.AmplitudeKnob.Value, app.SamplingRateSlider.Value, app.DropDown.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'LFO Applied to Input Wave')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitdue')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Generated LFO')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitdue')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sound')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @Sound_SliderValueChanged, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Filtered Signal (Time Domain)')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Amplitude')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Filtered Signal (Frequency Domain)')
            xlabel(app.UIAxes5, 'Frequency (hz)')
            ylabel(app.UIAxes5, 'Amplitude')
            zlabel(app.UIAxes5, 'Z')
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [200 700];
            app.FrequencySlider.MajorTicks = [200 300 400 500 600 700];
            app.FrequencySlider.MajorTickLabels = {'200', '300', '400', '500', '600', '700'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @Sound_SliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [];
            app.FrequencySlider.Value = 200;
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 2];
            app.AmplitudeKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.AmplitudeKnob.MinorTicks = [];
            app.AddNoiseLabel = uilabel(app.UIFigure);
            app.AddNoiseLabel.Text = 'Add Noise (%)';
            app.AddNoiseSlider = uislider(app.UIFigure);
            app.AddNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @AddNoiseSliderValueChanged, true);
            app.BandstopFilterLabel = uilabel(app.UIFigure);
            app.BandstopFilterLabel.Text = 'Bandstop Filter';
            app.UpperFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.UpperFrequencyEditFieldLabel.Text = 'Upper Frequency';
            app.UpperFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFrequencyEditFieldValueChanged, true);
            app.LowerFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.LowerFrequencyEditFieldLabel.Text = 'Lower Frequency';
            app.LowerFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFrequencyEditFieldValueChanged, true);
            app.LFOParametersLabel = uilabel(app.UIFigure);
            app.LFOParametersLabel.Text = 'LFO Parameters';
            app.SamplingRateSliderLabel = uilabel(app.UIFigure);
            app.SamplingRateSliderLabel.Text = 'Sampling Rate';
            app.SamplingRateSlider = uislider(app.UIFigure);
            app.SamplingRateSlider.Limits = [1 40];
            app.SamplingRateSlider.MajorTicks = [1 5 10 15 20 25 30 35 40];
            app.SamplingRateSlider.MajorTickLabels = {'1', '5', '10', '15', '20', '25', '30', '35', '40'};
            app.SamplingRateSlider.ValueChangedFcn = createCallbackFcn(app, @SamplingRateSliderValueChanged, true);
            app.SamplingRateSlider.MinorTicks = [];
            app.SamplingRateSlider.Value = 20;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sinewave', 'Squarewave', 'Sawtooth', 'Triangular'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Sinewave';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tomnook.jpg');
            app.SinewaveButton = uibutton(app.UIFigure, 'push');
            app.SinewaveButton.ButtonPushedFcn = createCallbackFcn(app, @SinewaveButtonPushed, true);
            app.SinewaveButton.Text = 'Sinewave';
            app.SquarewaveButton = uibutton(app.UIFigure, 'push');
            app.SquarewaveButton.ButtonPushedFcn = createCallbackFcn(app, @SquarewaveButtonPushed, true);
            app.SquarewaveButton.Text = 'Squarewave';
            app.SawtoothButton = uibutton(app.UIFigure, 'push');
            app.SawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothButtonPushed, true);
            app.SawtoothButton.Text = 'Sawtooth';
            app.TriangularButton = uibutton(app.UIFigure, 'push');
            app.TriangularButton.ButtonPushedFcn = createCallbackFcn(app, @TriangularButtonPushed, true);
            app.TriangularButton.Text = 'Triangular';
            app.SelectWaveLabel = uilabel(app.UIFigure);
            app.SelectWaveLabel.Text = 'Select Wave: ';
            app.SelectedWaveLabel = uilabel(app.UIFigure);
            app.SelectedWaveLabel.Text = 'Selected Wave: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynthApp_3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end